classdef fnirs_dataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        File         matlab.ui.container.Menu
        Open         matlab.ui.container.Menu
        Quit         matlab.ui.container.Menu
        Menu2        matlab.ui.container.Menu
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button3      matlab.ui.control.RadioButton
        Button2      matlab.ui.control.RadioButton
        Button       matlab.ui.control.RadioButton
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Open, Quit
        function OpenMenuSelected(app, event)
            switch event.Source.Text
                case 'Open'
                    disp('open');
                    [f,pth] = uigetfile('*.hdr','Select NIRx file to view');
                    [~,fname,~] = fileparts(f);
                    hdr = nirx_read_hdr(fullfile(pth,f));
                    [rdat,cols,S,D] = nirx_read_wl(fname,hdr);
                    disp([S D])

                case 'Quit'
                    disp('quit');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.Open = uimenu(app.File);
            app.Open.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.Open.Text = 'Open';
            app.Quit = uimenu(app.File);
            app.Quit.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.Quit.Text = 'Quit';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_dataViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end