classdef fnirs_markBadChans < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResulttoViewerButtonGroup   matlab.ui.container.ButtonGroup
        DeleteMarkedButton          matlab.ui.control.RadioButton
        MarksOnlyButton             matlab.ui.control.RadioButton
        badEditField                matlab.ui.control.NumericEditField
        badEditFieldLabel           matlab.ui.control.Label
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        LoadChannelLocationsButton  matlab.ui.control.Button
        SaveListButton              matlab.ui.control.Button
        MethodDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel         matlab.ui.control.Label
        ShowLabelsCheckBox          matlab.ui.control.CheckBox
        DoneButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hdr % header
        pos2d %  channel positions
        pos3d %  channel positions in 3d
        labels
        raw % raw data
        hb % hb data
        q
        bad
    end
    
    methods (Access = private)
        
        function plot2d(app,labelson)
            cla(app.UIAxes);
            scatter(app.UIAxes,app.pos2d(:,1),app.pos2d(:,2));
            hold(app.UIAxes,"on");
            if labelson
                for chn=1:length(app.pos2d)
                    text(app.UIAxes,app.pos2d(chn,1),app.pos2d(chn,2),app.labels(chn),'FontSize',8);
                end
            end
            scatter(app.UIAxes,app.pos2d(app.bad,1),app.pos2d(app.bad,2),'red');
            hold(app.UIAxes,"off");
            app.badEditField.Value = length(app.bad);
        end
        
        function calcBad(app,threshold)
            value = app.MethodDropDown.Value;
            switch value
                case "NIRx Signal Quality"
                    method = "NIRx";
                case "Coefficient of Variation"
                    method = "CV";
                case "Scalp Coupling Index"
                    method = "SCI";
                case "Autocorrelation Index"
                    method = "AI";
            end
            [app.q,~] = nirx_signal_quality(app.hdr,app.raw,'method',method,'threshold',threshold);
            switch value
                case "NIRx Signal Quality"
                    app.bad = app.q.NIRx_bad;
                case "Coefficient of Variation"
                    app.bad = app.q.CV_bad;
                case "Scalp Coupling Index"
                    app.bad = app.q.SCI_bad;
                case "Autocorrelation Index"
                    app.bad = app.q.AI_bad;
            end
            plot2d(app,app.ShowLabelsCheckBox.Value)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hdr, raw, Hb)
            app.hdr = hdr;
            app.raw = raw;
            app.hb = Hb;
            app.LoadChannelLocationsButton.Enable = "on";
            app.SaveListButton.Enable = "off";
            app.DoneButton.Enable = "off";
            app.ThresholdEditField.Enable = "off";
            app.MethodDropDown.Enable = "off";
            app.ThresholdEditField.Value = 1;
            [app.q,app.bad] = nirx_signal_quality(app.hdr,app.raw,'method','NIRX','threshold',app.ThresholdEditField.Value);
        end

        % Button pushed function: LoadChannelLocationsButton
        function LoadChannelLocationsButtonPushed(app, event)
            [posfile, pth, ~] = uigetfile('*.csv','Select file with optode positions...');
            [~,lbl,pos]=nirx_read_optpos(fullfile(pth,posfile));
            [app.pos3d, app.labels] = nirx_compute_chanlocs(lbl,pos,app.hdr.SDpairs,app.hdr.shortdetindex);
            app.pos2d = nirx_coords2D(app.pos3d);
            app.ThresholdEditField.Enable = "on";
            app.MethodDropDown.Enable = "on";
            app.SaveListButton.Enable = "on";
            app.DoneButton.Enable = "on";
            calcBad(app,app.ThresholdEditField.Value);
        end

        % Value changed function: ShowLabelsCheckBox
        function ShowLabelsCheckBoxValueChanged(app, event)
            plot2d(app,app.ShowLabelsCheckBox.Value);
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            switch value
                case "NIRx Signal Quality"
                    method = "NIRx";
                    app.ThresholdEditField.Value = 1;
                case "Coefficient of Variation"
                    method = "CV";
                    app.ThresholdEditField.Value = 7.5;
                case "Scalp Coupling Index"
                    app.ThresholdEditField.Value = .8;
                    method = "SCI";
                case "Autocorrelation Index"
                    app.ThresholdEditField.Value = .1;
                    method = "AI";
            end
            [app.q,~] = nirx_signal_quality(app.hdr,app.raw,'method',method,'threshold',app.ThresholdEditField.Value);
            switch value
                case "NIRx Signal Quality"
                    app.bad = app.q.NIRx_bad;
                case "Coefficient of Variation"
                    app.bad = app.q.CV_bad;
                case "Scalp Coupling Index"
                    app.bad = app.q.SCI_bad;
                case "Autocorrelation Index"
                    app.bad = app.q.AI_bad;
            end
            plot2d(app,app.ShowLabelsCheckBox.Value);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            calcBad(app,app.ThresholdEditField.Value);
        end

        % Button pushed function: SaveListButton
        function SaveListButtonPushed(app, event)
            [badfile, pth, ~] = uiputfile('*.csv','File name to save bad channels');
            fp = fopen(fullfile(pth,badfile),'w');
            fprintf(fp,'Channel,Source,Detector\n');
            badSD = app.hdr.SDpairs(app.bad,:);
            for chn = 1:length(app.bad)
                fprintf(fp,'%d,%d,%d\n',chn,badSD(chn,1),badSD(chn,2));
            end
            fclose(fp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.ShowLabelsCheckBox = uicheckbox(app.UIFigure);
            app.ShowLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLabelsCheckBoxValueChanged, true);
            app.ShowLabelsCheckBox.Text = 'Show Labels';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'NIRx Signal Quality', 'Coefficient of Variation', 'Scalp Coupling Index', 'Autocorrelation Index'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'NIRx Signal Quality';
            app.SaveListButton = uibutton(app.UIFigure, 'push');
            app.SaveListButton.ButtonPushedFcn = createCallbackFcn(app, @SaveListButtonPushed, true);
            app.SaveListButton.Text = 'Save List';
            app.LoadChannelLocationsButton = uibutton(app.UIFigure, 'push');
            app.LoadChannelLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelLocationsButtonPushed, true);
            app.LoadChannelLocationsButton.Text = 'Load Channel Locations';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.badEditFieldLabel = uilabel(app.UIFigure);
            app.badEditFieldLabel.Text = '# bad';
            app.badEditField = uieditfield(app.UIFigure, 'numeric');
            app.badEditField.Editable = 'off';
            app.ResulttoViewerButtonGroup = uibuttongroup(app.UIFigure);
            app.ResulttoViewerButtonGroup.Title = 'Result to Viewer';
            app.MarksOnlyButton = uiradiobutton(app.ResulttoViewerButtonGroup);
            app.MarksOnlyButton.Text = 'Marks Only';
            app.MarksOnlyButton.Value = true;
            app.DeleteMarkedButton = uiradiobutton(app.ResulttoViewerButtonGroup);
            app.DeleteMarkedButton.Text = 'Delete Marked';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_markBadChans(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end